classdef Boloc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BlcnhUIFigure  matlab.ui.Figure
        Panel_4        matlab.ui.container.Panel
        Panel_3        matlab.ui.container.Panel
        ngButton       matlab.ui.control.Button
        KhiphcButton   matlab.ui.control.Button
        MenuPanel      matlab.ui.container.Panel
        EditField_2    matlab.ui.control.NumericEditField
        EditField      matlab.ui.control.NumericEditField
        LcButton       matlab.ui.control.Button
        DropDown_2     matlab.ui.control.DropDown
        DropDown       matlab.ui.control.DropDown
        UIAxes2        matlab.ui.control.UIAxes
    end

    properties (Access = public)
        ProcessedImage 
        OriginalImage 
        CallingApp     
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            app.EditField.Editable = 'off';
            app.EditField_2.Editable = 'off';
    
    switch value
        case '2X2'
            app.EditField.Value = 2;
            app.EditField_2.Value = 2;
        case '3X3'
            app.EditField.Value = 3;
            app.EditField_2.Value = 3;
        case '4X4'
            app.EditField.Value = 4;
            app.EditField_2.Value = 4;
        case 'Tùy chọn'
            app.EditField.Editable = 'on';
            app.EditField_2.Editable = 'on';
            app.EditField.Value = 0;
            app.EditField_2.Value = 0;
    end

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Button pushed function: LcButton
        function LcButtonPushed(app, event)
            if isempty(app.ProcessedImage)
        uialert(app.BlcnhUIFigure, 'Vui lòng nạp ảnh trước!', 'Thông báo');
        return; 
    end
    sz_row = round(app.EditField.Value);
    sz_col = round(app.EditField_2.Value);
    if sz_row <= 0 || sz_col <= 0
        uialert(app.BlcnhUIFigure, 'Kích thước bộ lọc phải là số nguyên dương (lớn hơn 0)!', 'Lỗi nhập liệu');
        return; 
    end
    I = app.ProcessedImage;
    filterType = app.DropDown.Value;
    switch filterType
        case 'Lọc trung bình '
            h = fspecial('average', [sz_row sz_col]);
            app.OriginalImage = imfilter(I, h, 'replicate');
            
        case 'Lọc trung vị'
            if size(I, 3) == 3 
                r = medfilt2(I(:,:,1), [sz_row sz_col]);
                g = medfilt2(I(:,:,2), [sz_row sz_col]);
                b = medfilt2(I(:,:,3), [sz_row sz_col]);
                app.OriginalImage = cat(3, r, g, b);
            else 
                app.OriginalImage = medfilt2(I, [sz_row sz_col]);
            end
            
        case 'Lọc Gaussian'
            app.OriginalImage = imgaussfilt(I, (sz_row + sz_col)/4);
            
        otherwise
            app.OriginalImage = I;
    end
imshow(app.OriginalImage, 'Parent', app.UIAxes2);
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
             img = app.Image; 
            Boloc(app, img);
        end

        % Button pushed function: ngButton
        function ngButtonPushed(app, event)
            if isprop(app, 'CallingApp') && ~isempty(app.CallingApp) && isvalid(app.CallingApp)
            app.CallingApp.ImageFromEffect = app.OriginalImage;
         end
        delete(app.BlcnhUIFigure);
        end

        % Button pushed function: KhiphcButton
        function KhiphcButtonPushed(app, event)
             if isempty(app.ProcessedImage)
        return;
    end
    app.OriginalImage = app.ProcessedImage;
    imshow(app.ProcessedImage,'Parent',app.UIAxes2);
    app.DropDown.Value = 'Bộ lọc '; 
    app.DropDown_2.Value = 'Cửa sổ bộ lọc';
    app.EditField.Value = 0;
    app.EditField_2.Value = 0;
    app.EditField.Editable = 'off';
    app.EditField_2.Editable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BlcnhUIFigure = uifigure('Visible', 'off');
            app.BlcnhUIFigure.Name = 'Bộ lọc ảnh';
            app.UIAxes2 = uiaxes(app.BlcnhUIFigure);
            title(app.UIAxes2, 'Ảnh sau chỉnh sửa')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.DropDown = uidropdown(app.BlcnhUIFigure);
            app.DropDown.Items = {'Bộ lọc ', 'Lọc trung bình ', 'Lọc trung vị', 'Lọc Gaussian'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Bộ lọc ';
            app.Panel_4 = uipanel(app.BlcnhUIFigure);
            app.MenuPanel = uipanel(app.Panel_4);
            app.MenuPanel.Title = 'Menu';
            app.DropDown_2 = uidropdown(app.MenuPanel);
            app.DropDown_2.Items = {'Cửa sổ bộ lọc', '2X2', '3X3', '4X4', 'Tùy chọn'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Cửa sổ bộ lọc';
            app.LcButton = uibutton(app.MenuPanel, 'push');
            app.LcButton.ButtonPushedFcn = createCallbackFcn(app, @LcButtonPushed, true);
            app.LcButton.Text = 'Lọc';
            app.EditField = uieditfield(app.MenuPanel, 'numeric');
            app.EditField_2 = uieditfield(app.MenuPanel, 'numeric');
            app.Panel_3 = uipanel(app.Panel_4);
            app.KhiphcButton = uibutton(app.Panel_3, 'push');
            app.KhiphcButton.ButtonPushedFcn = createCallbackFcn(app, @KhiphcButtonPushed, true);
            app.KhiphcButton.Text = 'Khôi phục ';
            app.ngButton = uibutton(app.Panel_3, 'push');
            app.ngButton.ButtonPushedFcn = createCallbackFcn(app, @ngButtonPushed, true);
            app.ngButton.Text = 'Đóng';
            % Show the figure after all components are created
            app.BlcnhUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Boloc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BlcnhUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BlcnhUIFigure)
        end
    end
end