classdef Hieuungvapresetmau < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HiungvPresetmuUIFigure  matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        MenuPanel               matlab.ui.container.Panel
        ngButton                matlab.ui.control.Button
        KhiphcButton            matlab.ui.control.Button
        DropDown                matlab.ui.control.DropDown
        UIAxes2                 matlab.ui.control.UIAxes
    end

    properties (Access = public)
        ProcessedImage 
        OriginalImage  
        CallingApp
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if isempty(app.ProcessedImage), return; end
    
    I = app.ProcessedImage;
    I_result = I; 

    switch value
        case 'Đỏ'
            I_result(:,:,2:3) = 0;
        case 'Xanh lá'
            I_result(:,:,[1,3]) = 0;
        case 'Xanh dương'
            I_result(:,:,1:2) = 0;
        case 'Vàng'
            I_result(:,:,3) = 0;
        case 'Tím'
            I_result(:,:,2) = 0;
        case 'Âm bản'
            I_result = imcomplement(I);
        case 'Xám'
            if size(I, 3) == 3
                gray = rgb2gray(I);
                I_result = cat(3, gray, gray, gray);
            end
        case 'Đốm sáng'
            tempI = double(I) / 255;
            [r, c, ~] = size(tempI);
            [X, Y] = meshgrid(1:c, 1:r);
            dist = sqrt((X - c/2).^2 + (Y - r/2).^2);
            mask = exp(-(dist.^2) / (2 * (min(r,c)/3)^2));
            I_result = uint8((tempI .* mask) * 255);
        case 'Trái tim '
          I_result = I;
    [r,c,~] = size(I);

    numHearts = 35;  

    for k = 1:numHearts

        x0 = randi([1 r]);
        y0 = randi([1 c]);

        s = randi([15 45]);

        alpha = rand*0.5 + 0.3; 

        [X,Y] = meshgrid(linspace(-1.5,1.5,s));
        heart = (X.^2 + (-Y).^2 - 1).^3 - ...
                X.^2 .* (-Y).^3 < 0;

        color = [255 randi([80 150]) randi([80 150])];

        for ch = 1:3
            for i = 1:s
                for j = 1:s

                    if heart(i,j)

                        xx = x0+i;
                        yy = y0+j;

                        if xx<=r && yy<=c
                            I_result(xx,yy,ch) = ...
                              (1-alpha)*I_result(xx,yy,ch) + ...
                               alpha*color(ch);
                        end
                    end
                end
            end
        end
    end
    case 'Sao lấp lánh'

    I_result = I;
    [r,c,~] = size(I);

    numStars = 120;     
    maxSize = 4;        

    for k = 1:numStars

        x = randi(r);
        y = randi(c);
        s = randi([1 maxSize]);

        for dx = -s:s
            if x+dx>0 && x+dx<=r
                I_result(x+dx,y,:) = 255;
            end
        end

        for dy = -s:s
            if y+dy>0 && y+dy<=c
                I_result(x,y+dy,:) = 255;
            end
        end
    end
        case 'Bông tuyết'
            I_result = I;
            numSnow = round(0.01 * size(I,1) * size(I,2)); 
            for i = 1:numSnow
                I_result(randi(size(I,1)), randi(size(I,2)), :) = 255;
            end
        otherwise 
            I_result = I;
    end
    
    app.OriginalImage = I_result;
    imshow(I_result, 'Parent', app.UIAxes2);
      

        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            img = app.Image; 
            Hieuungvapresetmau(app, img);
        end

        % Button pushed function: KhiphcButton
        function KhiphcButtonPushed(app, event)
            if isempty(app.ProcessedImage)
        return;
    end
    app.OriginalImage = app.ProcessedImage;
    imshow(app.ProcessedImage,'Parent',app.UIAxes2);
     app.DropDown.Value = 'Preset';
        end

        % Button pushed function: ngButton
        function ngButtonPushed(app, event)
          if isprop(app, 'CallingApp') && ~isempty(app.CallingApp) && isvalid(app.CallingApp)
        
            app.CallingApp.ImageFromEffect = app.OriginalImage;
        
    end
             delete(app.HiungvPresetmuUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HiungvPresetmuUIFigure = uifigure('Visible', 'off');
            app.HiungvPresetmuUIFigure.Name = 'Hiệu ứng và Preset màu';
            app.UIAxes2 = uiaxes(app.HiungvPresetmuUIFigure);
            title(app.UIAxes2, 'Ảnh sau chỉnh sửa')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.Panel = uipanel(app.HiungvPresetmuUIFigure);
            app.MenuPanel = uipanel(app.Panel);
            app.MenuPanel.Title = 'Menu';
            app.DropDown = uidropdown(app.MenuPanel);
            app.DropDown.Items = {'Preset', 'Đỏ', 'Xanh lá', 'Xanh dương', 'Vàng', 'Tím', 'Âm bản', 'Xám', 'Trái tim ', 'Đốm sáng', 'Bông tuyết', 'Sao lấp lánh'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Preset';
            app.KhiphcButton = uibutton(app.MenuPanel, 'push');
            app.KhiphcButton.ButtonPushedFcn = createCallbackFcn(app, @KhiphcButtonPushed, true);
            app.KhiphcButton.Text = 'Khôi phục ';
            app.ngButton = uibutton(app.MenuPanel, 'push');
            app.ngButton.ButtonPushedFcn = createCallbackFcn(app, @ngButtonPushed, true);
            app.ngButton.Text = 'Đóng';
            % Show the figure after all components are created
            app.HiungvPresetmuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hieuungvapresetmau

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HiungvPresetmuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HiungvPresetmuUIFigure)
        end
    end
end