classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppchinhsuaanhUIFigure  matlab.ui.Figure
        InfoMenu                matlab.ui.container.Menu
        MenuPanel               matlab.ui.container.Panel
        Panel_2                 matlab.ui.container.Panel
        ngButton                matlab.ui.control.Button
        KhiphcButton            matlab.ui.control.Button
        LunhButton              matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        XoayphiButton           matlab.ui.control.Button
        XoaytriButton           matlab.ui.control.Button
        CtnhButton              matlab.ui.control.Button
        ChnnhButton             matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        HIungvPresetmuButton    matlab.ui.control.Button
        NtnhButton              matlab.ui.control.Button
        LcnhButton              matlab.ui.control.Button
        TngcngnhButton          matlab.ui.control.Button
        HinthButton             matlab.ui.control.Button
        PHNMMCHNHSANHLabel      matlab.ui.control.Label
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ProcessedImage % Description
        OriginalImage
        ExternalApp
        CallingApp
        ImageFromEffect
        PathFile
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Callback function: ChnnhButton, UIAxes_2
        function ChnnhButtonPushed(app, event)
           [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Chọn ảnh');
    if isequal(file, 0), return; end
    
    app.PathFile = fullfile(path, file);
    imgData = imread(app.PathFile);
    app.OriginalImage = imgData;
    app.ProcessedImage = imgData; 
    imshow(app.OriginalImage, 'Parent', app.UIAxes);
    imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
        
        end

        % Callback function
        function ChnnhButtonPushed2(app, event)
            
        end

        % Button pushed function: CtnhButton
        function CtnhButtonPushed(app, event)
           if isempty(app.ProcessedImage)
        uialert(app.AppchinhsuaanhUIFigure, 'Vui lòng chọn ảnh trước!', 'Lỗi');
        return;
    end
 [croppedImg, rect] = imcrop(app.UIAxes_2);

    if ~isempty(croppedImg)
        app.ProcessedImage = croppedImg;
        imshow(app.ProcessedImage, 'Parent', app.UIAxes_2); 
    end
    
        end

        % Button pushed function: XoaytriButton
        function XoaytriButtonPushed(app, event)
            if isempty(app.ProcessedImage)
                uialert(app.AppchinhsuaanhUIFigure, 'Vui lòng chọn ảnh trước khi xoay!', 'Thông báo');
                return;
            end
            app.ProcessedImage = imrotate(app.ProcessedImage, 90);
            imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
        end

        % Button pushed function: XoayphiButton
        function XoayphiButtonPushed(app, event)
            if isempty(app.ProcessedImage)
                uialert(app.AppchinhsuaanhUIFigure, 'Vui lòng chọn ảnh trước khi xoay!', 'Thông báo');
                return;
            end
            app.ProcessedImage = imrotate(app.ProcessedImage, -90);
            imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
        end

        % Callback function
        function LunhButtonValueChanged(app, event)
   
        end

        % Button pushed function: LunhButton
        function LunhButtonPushed(app, event)
            if isempty(app.ProcessedImage)
                uialert(app.AppchinhsuaanhUIFigure, 'Không có ảnh để lưu! Hãy chọn và chỉnh sửa ảnh trước.', 'Thông báo');
                return;
            end
        [file, path] = uiputfile({'*.jpg','JPEG Image (*.jpg)'; ...
                                      '*.png','PNG Image (*.png)'; ...
                                      '*.bmp','Bitmap Image (*.bmp)'}, ...
                                      'Lưu ảnh tại...');

            if ischar(file) && ischar(path)
                    fullFileName = fullfile(path, file);
                try
                   imwrite(app.ProcessedImage, fullFileName);
                    uialert(app.AppchinhsuaanhUIFigure, ['Đã lưu ảnh thành công: ' file], 'Thành công');
                catch ME
                    uialert(app.AppchinhsuaanhUIFigure, ['Lỗi khi lưu ảnh: ' ME.message], 'Lỗi');
                end
            end
        end

        % Button pushed function: KhiphcButton
        function KhiphcButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.AppchinhsuaanhUIFigure, 'Không có dữ liệu ảnh gốc để khôi phục!', 'Thông báo');
                return;
            end
            app.ProcessedImage = app.OriginalImage;
            imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];        
        end

        % Button pushed function: ngButton
        function ngButtonPushed(app, event)
 if ~isempty(app.ProcessedImage)
        selection = uiconfirm(app.AppchinhsuaanhUIFigure, ...
            'Bạn có muốn lưu thay đổi của ảnh hiện tại trước khi thoát không?', ...
            'Xác nhận thoát', ...
            'Options', {'Lưu', 'Không lưu', 'Hủy'}, ...
            'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'question');

        switch selection
            case 'Lưu'
                [file, path] = uiputfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Lưu ảnh trước khi thoát');
                if ischar(file)
                    imwrite(app.ProcessedImage, fullfile(path, file));
                    delete(app); 
                end
                
            case 'Không lưu'
                delete(app);
                
            case 'Hủy'
                return;
        end
    else
        delete(app);
 end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

       
        end

        % Callback function
        function ButtonGroupButtonDown(app, event)


        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown2(app, event)
            
        end

        % Button pushed function: HinthButton
        function HinthButtonPushed(app, event)
       if ~isempty(app.ImageFromEffect)
        app.ProcessedImage = app.ImageFromEffect;
        imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
        app.UIAxes_2.XTick = [];
        app.UIAxes_2.YTick = [];
        else
        uialert(app.AppchinhsuaanhUIFigure, 'Chưa có dữ liệu ảnh!', 'Thông báo');
       end
        end

        % Button pushed function: TngcngnhButton
        function TngcngnhButtonPushed(app, event)
         app.ExternalApp = app3(); 
         app.ExternalApp.CallingApp = app; 
         app.ExternalApp.ProcessedImage = app.ProcessedImage; 
         imshow(app.ExternalApp.ProcessedImage, 'Parent', app.ExternalApp.UIAxes2); 
        end

        % Button pushed function: LcnhButton
        function LcnhButtonPushed(app, event)
            app.ExternalApp = Boloc();
    app.ExternalApp.CallingApp = app;
    app.ExternalApp.ProcessedImage = app.ProcessedImage; 
    imshow(app.ExternalApp.ProcessedImage, 'Parent', app.ExternalApp.UIAxes2);
        end

        % Button pushed function: HIungvPresetmuButton
        function HIungvPresetmuButtonPushed(app, event)
         app.ExternalApp = Hieuungvapresetmau();
    app.ExternalApp.CallingApp = app;
    app.ExternalApp.ProcessedImage = app.ProcessedImage; 
    imshow(app.ExternalApp.ProcessedImage, 'Parent', app.ExternalApp.UIAxes2);
        end

        % Button pushed function: NtnhButton
        function NtnhButtonPushed(app, event)
            if isempty(app.ProcessedImage)
                uialert(app.AppchinhsuaanhUIFigure, 'Vui lòng chọn ảnh trước khi làm nét!', 'Thông báo');
                return;
            end
                I = im2double(app.ProcessedImage);
            hsv = rgb2hsv(I);
            hsv(:,:,3) = imsharpen(hsv(:,:,3), 'Radius', 1, 'Amount', 1.5);
            I_hsv_sharp = hsv2rgb(hsv);
            alpha = 0.2;
            h = fspecial('laplacian', alpha);
            L = imfilter(I, h, 'replicate');
            I_lap_sharp = I - L;
            I_lap_sharp = min(max(I_lap_sharp, 0), 1);
            app.ProcessedImage = im2uint8(I_lap_sharp); 
            imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];        
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
           if isempty(app.PathFile) || ~exist(app.PathFile, 'file')
        uialert(app.AppchinhsuaanhUIFigure, 'Vui lòng chọn ảnh trước!', 'Thông báo');
        return;
    end
    info = imfinfo(app.PathFile);
    [~, name, ext] = fileparts(app.PathFile);
    h = appInfo; 
    h.EditField_19.Editable    = 'off';
    h.EditField_21.Editable   = 'off';
    h.EditField_22.Editable = 'off';
    h.EditField_23.Editable   = 'off';
    h.EditField_24.Editable    = 'off';
    h.EditField_20.Editable  = 'off';
    h.EditField_19.Value    = [name, ext];
    h.EditField_21.Value   = info.FileSize; 
    h.EditField_22.Value   = info.Width;
    h.EditField_23.Value    = info.Height;
    h.EditField_24.Value    = info.BitDepth;
    h.EditField_20.Value   = info.ColorType;
    img = app.ProcessedImage;
   figure('Name', 'Bieu Do Muc Xam 3 Kenh', 'Color', 'white');
    subplot(3, 1, 1);
    imhist(img(:,:,1));
    title('Bieu Do Muc Xam Kenh Red');
    subplot(3, 1, 2);
    imhist(img(:,:,2));
    title('Bieu Do Muc Xam Kenh Green');
    subplot(3, 1, 3);
    imhist(img(:,:,3));
    title('Bieu Do Muc Xam Kenh Blue');     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppchinhsuaanhUIFigure = uifigure('Visible', 'off');
            app.AppchinhsuaanhUIFigure.Name = 'Appchinhsuaanh';
            app.InfoMenu = uimenu(app.AppchinhsuaanhUIFigure);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.UIAxes = uiaxes(app.AppchinhsuaanhUIFigure);
            title(app.UIAxes, 'Ảnh gốc')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.AppchinhsuaanhUIFigure);
            title(app.UIAxes_2, 'Ảnh sau chỉnh sửa')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @ChnnhButtonPushed, true);
            app.PHNMMCHNHSANHLabel = uilabel(app.AppchinhsuaanhUIFigure);
            app.PHNMMCHNHSANHLabel.Text = 'PHẦN MỀM CHỈNH SỬA ẢNH';
            app.MenuPanel = uipanel(app.AppchinhsuaanhUIFigure);
            app.MenuPanel.Title = 'Menu';
            app.ButtonGroup = uibuttongroup(app.MenuPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown2, true);
            app.HinthButton = uibutton(app.ButtonGroup, 'push');
            app.HinthButton.ButtonPushedFcn = createCallbackFcn(app, @HinthButtonPushed, true);
            app.HinthButton.Text = 'Hiển thị';
            app.TngcngnhButton = uibutton(app.ButtonGroup, 'push');
            app.TngcngnhButton.ButtonPushedFcn = createCallbackFcn(app, @TngcngnhButtonPushed, true);
            app.TngcngnhButton.Text = 'Tăng cường ảnh';
            app.LcnhButton = uibutton(app.ButtonGroup, 'push');
            app.LcnhButton.ButtonPushedFcn = createCallbackFcn(app, @LcnhButtonPushed, true);
            app.LcnhButton.Text = 'Lọc ảnh';
            app.NtnhButton = uibutton(app.ButtonGroup, 'push');
            app.NtnhButton.ButtonPushedFcn = createCallbackFcn(app, @NtnhButtonPushed, true);
            app.NtnhButton.Text = 'Nét ảnh';
            app.HIungvPresetmuButton = uibutton(app.ButtonGroup, 'push');
            app.HIungvPresetmuButton.ButtonPushedFcn = createCallbackFcn(app, @HIungvPresetmuButtonPushed, true);
            app.HIungvPresetmuButton.Text = 'HIệu ứng và Preset màu';
            app.ChnnhButton = uibutton(app.MenuPanel, 'push');
            app.ChnnhButton.ButtonPushedFcn = createCallbackFcn(app, @ChnnhButtonPushed, true);
            app.ChnnhButton.Text = 'Chọn ảnh';
            app.Panel = uipanel(app.MenuPanel);
            app.CtnhButton = uibutton(app.Panel, 'push');
            app.CtnhButton.ButtonPushedFcn = createCallbackFcn(app, @CtnhButtonPushed, true);
            app.CtnhButton.Text = 'Cắt ảnh';
            app.XoaytriButton = uibutton(app.Panel, 'push');
            app.XoaytriButton.ButtonPushedFcn = createCallbackFcn(app, @XoaytriButtonPushed, true);
            app.XoaytriButton.Text = 'Xoay trái';
            app.XoayphiButton = uibutton(app.Panel, 'push');
            app.XoayphiButton.ButtonPushedFcn = createCallbackFcn(app, @XoayphiButtonPushed, true);
            app.XoayphiButton.Text = 'Xoay phải';
            app.Panel_2 = uipanel(app.MenuPanel);
            app.LunhButton = uibutton(app.Panel_2, 'push');
            app.LunhButton.ButtonPushedFcn = createCallbackFcn(app, @LunhButtonPushed, true);
            app.LunhButton.Text = 'Lưu ảnh';
            app.KhiphcButton = uibutton(app.Panel_2, 'push');
            app.KhiphcButton.ButtonPushedFcn = createCallbackFcn(app, @KhiphcButtonPushed, true);
            app.KhiphcButton.Text = 'Khôi phục';
            app.ngButton = uibutton(app.Panel_2, 'push');
            app.ngButton.ButtonPushedFcn = createCallbackFcn(app, @ngButtonPushed, true);
            app.ngButton.Text = 'Đóng';
            % Show the figure after all components are created
            app.AppchinhsuaanhUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppchinhsuaanhUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppchinhsuaanhUIFigure)
        end
    end
end