classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TngcngnhUIFigure   matlab.ui.Figure
        MenuPanel          matlab.ui.container.Panel
        EditField_2        matlab.ui.control.NumericEditField
        EditField          matlab.ui.control.NumericEditField
        ngButton           matlab.ui.control.Button
        KhiphcButton       matlab.ui.control.Button
        CnbnglcxmButton    matlab.ui.control.Button
        sngSlider          matlab.ui.control.Slider
        sngSliderLabel     matlab.ui.control.Label
        tngphnSlider       matlab.ui.control.Slider
        tngphnSliderLabel  matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        UIAxes2            matlab.ui.control.UIAxes
    end

    properties (Access = public)
   ProcessedImage 
   OriginalImage  
   CallingApp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            colorDisabled = [0.90 0.90 0.90]; 
            colorActive = [1 1 1];

          if strcmp(value, 'Độ tương phản')
                app.tngphnSlider.Enable = 'on';
                app.tngphnSliderLabel.Enable = 'on';
                app.EditField.BackgroundColor = colorActive;
            else
                app.tngphnSlider.Enable = 'off';
                app.tngphnSliderLabel.Enable = 'off';
                app.EditField.BackgroundColor = colorDisabled; 
            end

           
            if strcmp(value, 'Độ sáng')
                app.sngSlider.Enable = 'on';
                app.sngSliderLabel.Enable = 'on';
                app.EditField_2.BackgroundColor = colorActive;
            else
                app.sngSlider.Enable = 'off';
                app.sngSliderLabel.Enable = 'off';
                app.EditField_2.BackgroundColor = colorDisabled;
            end
            
            app.CnbnglcxmButton.Enable = strcmp(value, 'Cân bằng lược đồ xám');
        
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
      img = app.Image; 
     app3(app, img);
        end

        % Value changing function: tngphnSlider
        function tngphnSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue; 
        if isempty(app.ProcessedImage) 
            return; 
    end
    alpha = (changingValue / 10) * 1.5 + 0.5;
    I_new = immultiply(app.ProcessedImage, alpha);
    imshow(I_new, 'Parent', app.UIAxes2);
    app.OriginalImage = I_new;
       
        end

        % Value changing function: sngSlider
        function sngSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value = changingValue;
            if isempty(app.ProcessedImage)
                return; 
            end

    I_new = imadd(app.ProcessedImage, changingValue);
    imshow(I_new, 'Parent', app.UIAxes2);
    app.OriginalImage = I_new;       
        end

        % Button pushed function: CnbnglcxmButton
        function CnbnglcxmButtonPushed(app, event)
          
            if isempty(app.ProcessedImage)
                return;
            end
            
           I = app.ProcessedImage;
           if size(I, 3) == 3
               hsv_img = rgb2hsv(I);
                v_channel = hsv_img(:,:,3);
               v_channel = histeq(v_channel);
               hsv_img(:,:,3) = v_channel;
                I_new = hsv2rgb(hsv_img);
            else
                I_new = histeq(I);
            end
            imshow(I_new, 'Parent', app.UIAxes2);
            app.OriginalImage = I_new; 
        
        end

        % Button pushed function: ngButton
        function ngButtonPushed(app, event)

          if isprop(app, 'CallingApp') && ~isempty(app.CallingApp) && isvalid(app.CallingApp)
     
            app.CallingApp.ImageFromEffect = app.OriginalImage;
        
    end
  
             delete(app.TngcngnhUIFigure);
        
        end

        % Button pushed function: KhiphcButton
        function KhiphcButtonPushed(app, event)
        if isempty(app.ProcessedImage)
             return;
         end
    app.OriginalImage = app.ProcessedImage;
    imshow(app.OriginalImage, 'Parent', app.UIAxes2);
    app.tngphnSlider.Value = 0;
    app.sngSlider.Value = 0;
    app.EditField.Value = 0;
    app.EditField_2.Value = 0;
    app.DropDown.Value = 'Menu';
    app.DropDownValueChanged(event);     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TngcngnhUIFigure = uifigure('Visible', 'off');
            app.TngcngnhUIFigure.Name = 'Tăng cường ảnh';
            app.UIAxes2 = uiaxes(app.TngcngnhUIFigure);
            title(app.UIAxes2, 'Ảnh sau chỉnh sửa')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.DropDown = uidropdown(app.TngcngnhUIFigure);
            app.DropDown.Items = {'Menu', 'Độ tương phản', 'Độ sáng', 'Cân bằng lược đồ xám'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'Menu';
            app.MenuPanel = uipanel(app.TngcngnhUIFigure);
            app.MenuPanel.Title = 'Menu';
            app.tngphnSliderLabel = uilabel(app.MenuPanel);
            app.tngphnSliderLabel.Enable = 'off';
            app.tngphnSliderLabel.Text = 'Độ tương phản';
            app.tngphnSlider = uislider(app.MenuPanel);
            app.tngphnSlider.Limits = [0 10];
            app.tngphnSlider.ValueChangingFcn = createCallbackFcn(app, @tngphnSliderValueChanging, true);
            app.tngphnSlider.Enable = 'off';
            app.sngSliderLabel = uilabel(app.MenuPanel);
            app.sngSliderLabel.Enable = 'off';
            app.sngSliderLabel.Text = 'Độ sáng';
            app.sngSlider = uislider(app.MenuPanel);
            app.sngSlider.Limits = [0 10];
            app.sngSlider.ValueChangingFcn = createCallbackFcn(app, @sngSliderValueChanging, true);
            app.sngSlider.Enable = 'off';
            app.CnbnglcxmButton = uibutton(app.MenuPanel, 'push');
            app.CnbnglcxmButton.ButtonPushedFcn = createCallbackFcn(app, @CnbnglcxmButtonPushed, true);
            app.CnbnglcxmButton.Enable = 'off';
            app.CnbnglcxmButton.Text = 'Cân bằng lược đồ xám';
            app.KhiphcButton = uibutton(app.MenuPanel, 'push');
            app.KhiphcButton.ButtonPushedFcn = createCallbackFcn(app, @KhiphcButtonPushed, true);
            app.KhiphcButton.Text = 'Khôi phục ';
            app.ngButton = uibutton(app.MenuPanel, 'push');
            app.ngButton.ButtonPushedFcn = createCallbackFcn(app, @ngButtonPushed, true);
            app.ngButton.Text = 'Đóng';
            app.EditField = uieditfield(app.MenuPanel, 'numeric');
            app.EditField_2 = uieditfield(app.MenuPanel, 'numeric');
            % Show the figure after all components are created
            app.TngcngnhUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TngcngnhUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TngcngnhUIFigure)
        end
    end
end